classdef hist_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        helponhistogrammatchingPanel   matlab.ui.container.Panel
        TextAreaLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        currentmodulehistogrammatchingPanel  matlab.ui.container.Panel
        startButton                    matlab.ui.control.Button
        parametersPanel                matlab.ui.container.Panel
        setthetragethistogramDropDownLabel  matlab.ui.control.Label
        setthetragethistogramDropDown  matlab.ui.control.DropDown
        saveallButton                  matlab.ui.control.Button
        imagespathPanel                matlab.ui.container.Panel
        inputimagesTextAreaLabel       matlab.ui.control.Label
        inputimagesTextArea            matlab.ui.control.TextArea
        pathofinputimagesButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        %new
        input_images_paths%路径+文件名 字符向量元胞数组
        input_paths%路径 字符向量元胞数组
        target_hist%目标直方图
        %new-end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if isempty(app.input_images_paths)
                alart_message='There is not the images to be processed!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(app.input_images_paths)
                    Trans_Image=histMatching(app.input_images_paths{i},app.target_hist);
                    output_img = make_nii(Trans_Image);
                    input_data=load_untouch_nii(app.input_images_paths{i});
                    output_img.hdr = input_data.hdr;
                    C=split(app.input_images_paths{i},'.');
                    save_path=strcat(C{1},'_matched.nii.gz');
                    save_nii(output_img, save_path);
                     %进度条更新
                    progress_bar.Value=i/length(app.input_images_paths);
                    if i~=length(app.input_images_paths)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
                uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
            end
           
        end

        % Value changed function: setthetragethistogramDropDown
        function setthetragethistogramDropDownValueChanged(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist='Target_Hist_TubeTK.mat';
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist=Target_Hist_TubeTK;
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
            app.output_prefix=app.filename_prefix_EditField.Value;
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
            input_path_and_name=cell(1,length(filename));
            disp(filename)
            for i=1:length(filename)
                input_file_path=[file_path,filename{i}];
                input_path_and_name{i}=input_file_path;
            end
            
            app.input_images_paths=[app.input_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字
            disp(app.input_images_paths)
            app.inputimagesTextArea.Value=app.input_images_paths;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponhistogrammatchingPanel = uipanel(app.UIFigure);
            app.helponhistogrammatchingPanel.Title = 'help on histogram matching';
            app.TextAreaLabel = uilabel(app.helponhistogrammatchingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponhistogrammatchingPanel);
            app.currentmodulehistogrammatchingPanel = uipanel(app.UIFigure);
            app.currentmodulehistogrammatchingPanel.Title = 'current module:histogram matching';
            app.startButton = uibutton(app.currentmodulehistogrammatchingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulehistogrammatchingPanel);
            app.parametersPanel.Title = 'parameters';
            app.setthetragethistogramDropDownLabel = uilabel(app.parametersPanel);
            app.setthetragethistogramDropDownLabel.Text = 'set the traget histogram';
            app.setthetragethistogramDropDown = uidropdown(app.parametersPanel);
            app.setthetragethistogramDropDown.Items = {'MIDAS', 'TubeTK', 'Option 3', 'Option 4'};
            app.setthetragethistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @setthetragethistogramDropDownValueChanged, true);
            app.setthetragethistogramDropDown.Value = 'MIDAS';
            app.saveallButton = uibutton(app.parametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.imagespathPanel = uipanel(app.currentmodulehistogrammatchingPanel);
            app.imagespathPanel.Title = 'image(s) path';
            app.inputimagesTextAreaLabel = uilabel(app.imagespathPanel);
            app.inputimagesTextAreaLabel.Text = 'input image(s)';
            app.inputimagesTextArea = uitextarea(app.imagespathPanel);
            app.pathofinputimagesButton = uibutton(app.imagespathPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hist_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end